classdef CCTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        HOLDGRAPHButton                 matlab.ui.control.StateButton
        PUMPOFFButton                   matlab.ui.control.Button
        PUMPONButton                    matlab.ui.control.Button
        FlowMonitoringControlPanel      matlab.ui.container.Panel
        GraphLabel_2                    matlab.ui.control.Label
        Flow_IVC_GraphEnable            matlab.ui.control.CheckBox
        Flow_SVC_GraphEnable            matlab.ui.control.CheckBox
        AllvaluesinLminLabel            matlab.ui.control.Label
        Flow_SVC_EditField_Current      matlab.ui.control.NumericEditField
        SVCEditField_2Label             matlab.ui.control.Label
        Flow_IVC_EditField_Current      matlab.ui.control.NumericEditField
        IVCEditField_2Label             matlab.ui.control.Label
        SetpointLabel_2                 matlab.ui.control.Label
        CurrentLabel_2                  matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PressureTab                     matlab.ui.container.Tab
        PressureAxes                    matlab.ui.control.UIAxes
        FlowTab                         matlab.ui.container.Tab
        FlowAxes                        matlab.ui.control.UIAxes
        PressureMonitoringControlPanel  matlab.ui.container.Panel
        Pressure_IVC_GraphEnable        matlab.ui.control.CheckBox
        Pressure_SVC_GraphEnable        matlab.ui.control.CheckBox
        Pressure_PA_GraphEnable         matlab.ui.control.CheckBox
        GraphLabel                      matlab.ui.control.Label
        LocationLabel                   matlab.ui.control.Label
        AllvaluesinmmHgLabel            matlab.ui.control.Label
        Pressure_PA_EditField_Current   matlab.ui.control.NumericEditField
        PAEditFieldLabel                matlab.ui.control.Label
        Pressure_SVC_EditField_Current  matlab.ui.control.NumericEditField
        SVCEditFieldLabel               matlab.ui.control.Label
        Pressure_IVC_EditField_Current  matlab.ui.control.NumericEditField
        IVCEditFieldLabel               matlab.ui.control.Label
        SetpointLabel                   matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        DeveloperModeTab                matlab.ui.container.Tab
        SavelogfileButton               matlab.ui.control.Button
        SensorDataTextArea              matlab.ui.control.TextArea
        SensorDataTextAreaLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        plot_raw_data = true;
        COM_PORT = serialportlist;  % works automatically if only one serial port device is connected
        f_refresh = 20;  % App refresh rate (Hz)

        sensorDataLog = "";  % to store a log of recent sensor data values for developer tab

        arduinoObj;
        tempLogFile = fullfile(pwd, 'console_output_temp.txt');  % Define a persistent temporary log file that always stores session history

        t = [];
        flow_vals1 = [];  % Containers for sensor data
        flow_vals2 = [];

        pressure_vals1 = [];
        pressure_vals2 = [];
        pressure_vals3 = [];
    end

    properties (Dependent)
        dt_refresh
    end

    methods
        function value = get.dt_refresh(app)
            value = 1 / app.f_refresh;  % Compute refresh interval dynamically
        end
    end

    methods (Access = private)

        function [newFlowIndicator, flow_value_raw1, flow_value1, ...
                flow_value_raw2, flow_value2, pressure_value_raw1, ...
                pressure_value1, pressure_value_raw2, pressure_value2, ...
                pressure_value_raw3, pressure_value3] = readSensorData(app)

            % Reads and parses JSON data from the Arduino serial port.
            % Inputs:
            %   serialObj - MATLAB serial object for Arduino communication.
            % Outputs:
            %   flow_value_raw - Raw ADC value for flow sensor.
            %   flow_value - Processed flow rate in L/min.
            %   pressure_value_raw - Raw ADC value for pressure sensor.
            %   pressure_value - Processed pressure in mmHg.

            % Flush serial buffer by reading three times
            for j = 1:3
                line = readline(app.arduinoObj);
            end

            % Attempt to parse the serial data
            try
                % Expected format:
                % "New Flow?: Y, Flow Raw 1: 120, Flow 1: 3.50 L/min, Flow Raw 2: 110, Flow 2: 3.20 L/min,
                % Pressure Raw 1: 512, Pressure 1: 75.3 mmHg, Pressure Raw 2: 523, Pressure 2: 76.1 mmHg,
                % Pressure Raw 3: 540, Pressure 3: 78.4 mmHg"

                % Extract values using sscanf
                data = sscanf(line, 'New Flow?: %c, Flow Raw 1: %d, Flow 1: %f L/min, Flow Raw 2: %d, Flow 2: %f L/min, Pressure Raw 1: %d, Pressure 1: %f mmHg, Pressure Raw 2: %d, Pressure 2: %f mmHg, Pressure Raw 3: %d, Pressure 3: %f mmHg');

                % Ensure we received all expected values
                if numel(data) == 11
                    newFlowIndicator = char(data(1));   % 'Y' or 'N'
                    flow_value_raw1 = data(2);
                    flow_value1 = data(3);
                    flow_value_raw2 = data(4);
                    flow_value2 = data(5);
                    pressure_value_raw1 = data(6);
                    pressure_value1 = data(7);
                    pressure_value_raw2 = data(8);
                    pressure_value2 = data(9);
                    pressure_value_raw3 = data(10);
                    pressure_value3 = data(11);

                    % Get timestamp
                    timestamp = datetime("now", 'yyyy-mm-dd HH:MM:SS');

                    % Format the new sensor data as a string
                    newLogEntry = sprintf(['===== Sensor Data (%s) =====\n', ...
                        'New Flow?: %c\n', ...
                        'Flow Raw 1: %d, Flow 1: %.2f L/min\n', ...
                        'Flow Raw 2: %d, Flow 2: %.2f L/min\n', ...
                        'Pressure Raw 1: %d, Pressure 1: %.2f mmHg\n', ...
                        'Pressure Raw 2: %d, Pressure 2: %.2f mmHg\n', ...
                        'Pressure Raw 3: %d, Pressure 3: %.2f mmHg\n\n'], ...
                        timestamp, newFlowIndicator, ...
                        flow_value_raw1, flow_value1, ...
                        flow_value_raw2, flow_value2, ...
                        pressure_value_raw1, pressure_value1, ...
                        pressure_value_raw2, pressure_value2, ...
                        pressure_value_raw3, pressure_value3);

                    % Print to console
                    fprintf('%s', newLogEntry);

                    % Append to the stored console log
                    app.sensorDataLog = strcat(app.sensorDataLog, newLogEntry);

                    % Limit console log size to the last 100 entries (to prevent slowdowns)
                    maxEntries = 100;
                    logLines = strsplit(app.sensorDataLog, '\n'); % Split into individual lines
                    if length(logLines) > maxEntries * 9  % Each entry has around 9 lines
                        logLines = logLines(end - (maxEntries * 9) + 1:end); % Keep only the last 100 entries
                    end
                    app.sensorDataLog = strjoin(logLines, '\n'); % Reconstruct the trimmed log

                    % Update text area
                    app.ConsoleOutputTextArea.Value = app.sensorDataLog;
                else
                    warning('Failed to parse expected number of values from serial data.');
                end
            catch
                warning('Error reading and parsing serial data.');
            end

        end

        function startLogging(app)
            % Start recording console output to the temporary file
            diary(app.tempLogFile);
            diary on;

            % Notify user
            fprintf('Logging started. All session logs will be recorded in: %s\n', app.tempLogFile);
        end

        function stopLogging(app)
            % Stop logging to the temporary file
            diary off;

            % Define a unique filename for saving the log snapshot
            timestamp = datetime("now", 'yyyy-mm-dd_HH-MM-SS');
            savedLogFile = fullfile(pwd, ['sensor_log_' timestamp '.txt']);

            % Copy the temp log file to the new timestamped log file
            copyfile(app.tempLogFile, savedLogFile);

            % Notify user
            fprintf('Log file saved as: %s\n', savedLogFile);

            % Restart logging to the temporary file to maintain session history
            diary(app.tempLogFile);
            diary on;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoObj = serialport(app.COM_PORT, 9600);

            tic;
            app.startLogging();
            while (1)
                % Read sensor data
                [~, ~, flow_value1, ~, flow_value2, ...
                    ~, pressure_value1, ~, pressure_value2, ...
                    ~, pressure_value3] = app.readSensorData();

                % Append new sensor data
                app.flow_vals1 = [app.flow_vals1 flow_value1];
                app.flow_vals2 = [app.flow_vals2 flow_value2];

                app.pressure_vals1 = [app.pressure_vals1 pressure_value1];
                app.pressure_vals2 = [app.pressure_vals2 pressure_value2];
                app.pressure_vals3 = [app.pressure_vals3 pressure_value3];

                % Update Edit Fields with the latest sensor data
                app.Pressure_PA_EditField_Current.Value = pressure_value1;
                app.Pressure_SVC_EditField_Current.Value = pressure_value2;
                app.Pressure_IVC_EditField_Current.Value = pressure_value3;

                app.Flow_IVC_EditField_Current.Value = flow_value1;
                app.Flow_SVC_EditField_Current.Value = flow_value2;

                % Update time tracking
                app.t = [app.t toc];

                % Plot pressure data based on checkbox selections
                % as long as "hold graph" is not pressed
                if app.HOLDGRAPHButton.Value == false
                    hold(app.PressureAxes, 'on');
                    cla(app.PressureAxes); % Clear previous plots

                    if app.Pressure_PA_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals1, 'r', 'DisplayName', 'Pressure PA');
                    end
                    if app.Pressure_SVC_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals2, 'g', 'DisplayName', 'Pressure SVC');
                    end
                    if app.Pressure_IVC_GraphEnable.Value
                        plot(app.PressureAxes, app.t, app.pressure_vals3, 'b', 'DisplayName', 'Pressure IVC');
                    end

                    hold(app.PressureAxes, 'off');
                    title(app.PressureAxes, 'Pressure Data');
                    xlabel(app.PressureAxes, 'Time (s)');
                    ylabel(app.PressureAxes, 'Pressure (mmHg)');
                    ylim(app.PressureAxes, [0 200]);
                    legend(app.PressureAxes, 'show');

                    % Plot flow data based on checkbox selections
                    hold(app.FlowAxes, 'on');
                    cla(app.FlowAxes); % Clear previous plots

                    if app.Flow_SVC_GraphEnable.Value
                        plot(app.FlowAxes, app.t, app.flow_vals1, 'r', 'DisplayName', 'Flow SVC');
                    end
                    if app.Flow_IVC_GraphEnable.Value
                        plot(app.FlowAxes, app.t, app.flow_vals2, 'g', 'DisplayName', 'Flow IVC');
                    end

                    hold(app.FlowAxes, 'off');
                    title(app.FlowAxes, 'Flow Data');
                    xlabel(app.FlowAxes, 'Time (s)');
                    ylabel(app.FlowAxes, 'Flow (L/min)');
                    ylim(app.FlowAxes, [0 5]);
                    legend(app.FlowAxes, 'show');
                end

                % Pause to prevent crashes
                pause(app.dt_refresh);
            end

        end

        % Button pushed function: SavelogfileButton
        function SavelogfileButtonPushed(app, event)
            app.stopLogging();
            app.startLogging();  % continue logging
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.Title = uilabel(app.MainTab);
            app.Title.Text = 'Cardiac Catheterization Testing Apparatus';
            app.PressureMonitoringControlPanel = uipanel(app.MainTab);
            app.PressureMonitoringControlPanel.Title = 'Pressure Monitoring & Control';
            app.Label = uilabel(app.PressureMonitoringControlPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.EditField_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.EditField_3.Enable = 'off';
            app.CurrentLabel = uilabel(app.PressureMonitoringControlPanel);
            app.CurrentLabel.Text = 'Current';
            app.SetpointLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SetpointLabel.Text = 'Setpoint';
            app.IVCEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.IVCEditFieldLabel.Text = 'IVC';
            app.Pressure_IVC_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.SVCEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SVCEditFieldLabel.Text = 'SVC';
            app.Pressure_SVC_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.PAEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.PAEditFieldLabel.Text = 'PA';
            app.Pressure_PA_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.AllvaluesinmmHgLabel = uilabel(app.PressureMonitoringControlPanel);
            app.AllvaluesinmmHgLabel.Text = 'All values in mmHg';
            app.LocationLabel = uilabel(app.PressureMonitoringControlPanel);
            app.LocationLabel.Text = 'Location';
            app.GraphLabel = uilabel(app.PressureMonitoringControlPanel);
            app.GraphLabel.Text = 'Graph?';
            app.Pressure_PA_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure_PA_GraphEnable.Text = '';
            app.Pressure_PA_GraphEnable.Value = true;
            app.Pressure_SVC_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure_SVC_GraphEnable.Text = '';
            app.Pressure_SVC_GraphEnable.Value = true;
            app.Pressure_IVC_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure_IVC_GraphEnable.Text = '';
            app.Pressure_IVC_GraphEnable.Value = true;
            app.TabGroup = uitabgroup(app.MainTab);
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.PressureAxes = uiaxes(app.PressureTab);
            title(app.PressureAxes, 'Title')
            xlabel(app.PressureAxes, 'X')
            ylabel(app.PressureAxes, 'Y')
            zlabel(app.PressureAxes, 'Z')
            app.FlowTab = uitab(app.TabGroup);
            app.FlowTab.Title = 'Flow';
            app.FlowAxes = uiaxes(app.FlowTab);
            title(app.FlowAxes, 'Title')
            xlabel(app.FlowAxes, 'X')
            ylabel(app.FlowAxes, 'Y')
            zlabel(app.FlowAxes, 'Z')
            app.FlowMonitoringControlPanel = uipanel(app.MainTab);
            app.FlowMonitoringControlPanel.Title = 'Flow Monitoring & Control';
            app.Label_2 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_2.Text = '';
            app.EditField_4 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.EditField_5.Enable = 'off';
            app.CurrentLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.CurrentLabel_2.Text = 'Current';
            app.SetpointLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.SetpointLabel_2.Text = 'Setpoint';
            app.IVCEditField_2Label = uilabel(app.FlowMonitoringControlPanel);
            app.IVCEditField_2Label.Text = 'IVC';
            app.Flow_IVC_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.SVCEditField_2Label = uilabel(app.FlowMonitoringControlPanel);
            app.SVCEditField_2Label.Text = 'SVC';
            app.Flow_SVC_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.AllvaluesinLminLabel = uilabel(app.FlowMonitoringControlPanel);
            app.AllvaluesinLminLabel.Text = 'All values in L/min';
            app.Flow_SVC_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow_SVC_GraphEnable.Text = '';
            app.Flow_SVC_GraphEnable.Value = true;
            app.Flow_IVC_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow_IVC_GraphEnable.Text = '';
            app.Flow_IVC_GraphEnable.Value = true;
            app.GraphLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.GraphLabel_2.Text = 'Graph?';
            app.PUMPONButton = uibutton(app.MainTab, 'push');
            app.PUMPONButton.Enable = 'off';
            app.PUMPONButton.Text = 'PUMP ON';
            app.PUMPOFFButton = uibutton(app.MainTab, 'push');
            app.PUMPOFFButton.Enable = 'off';
            app.PUMPOFFButton.Text = 'PUMP OFF';
            app.HOLDGRAPHButton = uibutton(app.MainTab, 'state');
            app.HOLDGRAPHButton.Text = 'HOLD GRAPH';
            app.DeveloperModeTab = uitab(app.TabGroup2);
            app.DeveloperModeTab.Title = 'Developer Mode';
            app.SensorDataTextAreaLabel = uilabel(app.DeveloperModeTab);
            app.SensorDataTextAreaLabel.Text = 'Sensor Data ';
            app.SensorDataTextArea = uitextarea(app.DeveloperModeTab);
            app.SavelogfileButton = uibutton(app.DeveloperModeTab, 'push');
            app.SavelogfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogfileButtonPushed, true);
            app.SavelogfileButton.Text = 'Save log file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCTA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end